classdef M6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        InstructionsTab              matlab.ui.container.Tab
        DASHLabel                    matlab.ui.control.Label
        Image3                       matlab.ui.control.Image
        DOTLabel                     matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        ThisistheMorseCodeTypingtestLabel  matlab.ui.control.Label
        PressToContinueButton        matlab.ui.control.Button
        TrialTab                     matlab.ui.container.Tab
        resultButton                 matlab.ui.control.Button
        trialInstruction             matlab.ui.control.Label
        YourInputLabel               matlab.ui.control.Label
        morseInput                   matlab.ui.control.Label
        trialImage                   matlab.ui.control.Image
        LetsTryUsingBasicCharactersLabel  matlab.ui.control.Label
        InformationTab               matlab.ui.container.Tab
        NotePut0asyourgradeifyouarenotinschoolLabel  matlab.ui.control.Label
        gradeInput                   matlab.ui.control.DropDown
        EnteryourGradeDropDownLabel  matlab.ui.control.Label
        ageInput                     matlab.ui.control.DropDown
        EnteryourAgeDropDownLabel    matlab.ui.control.Label
        StartGame                    matlab.ui.control.Button
        GameTab                      matlab.ui.container.Tab
        sLabel                       matlab.ui.control.Label
        Debug2                       matlab.ui.control.Label
        Debug1                       matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        LetterInputDynamic           matlab.ui.control.Label
        LetterInputLabel             matlab.ui.control.Label
        MorseInputLabel              matlab.ui.control.Label
        MorseInputDynamic            matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        StatisticsTab                matlab.ui.container.Tab
        CongratulationsonCompletingtheTypingTestLabel  matlab.ui.control.Label
        YourAverageSpeedinCharactersPerMinuteLabel  matlab.ui.control.Label
        YourAccuracyinageLabel       matlab.ui.control.Label
        averageSpeedOutput           matlab.ui.control.Label
        accuracyOutput               matlab.ui.control.Label
        ClicktoContinueButton        matlab.ui.control.Button
        QRCodeTab                    matlab.ui.container.Tab
        LastContinueButton           matlab.ui.control.Button
        Image4                       matlab.ui.control.Image
        Label3                       matlab.ui.control.Label
        ThankYouforUsingtheMorseCodeTypingTestLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartGame
        function StartGameButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.GameTab;
            tic

%           Takes the grade and age input from he user 
            gradeInput = app.gradeInput.Value;
            ageInput = app.ageInput.Value;
%           Makes the timer label visible
            app.Label.Visible = 'on';
            
%           Initiates a timer object and displays the timer in the right 
%           corner of the game tab
            t = timer;
            t.Period = 1;
            t.ExecutionMode = 'fixedRate';
            t.TasksToExecute = 32;
            t.TimerFcn = @(~, thisEvent) timerLabel(app);
%           Starts the timer
            start(t);
%           Runs the function with the grade and age input as parameters
            MorseKeyer7(app, gradeInput, ageInput);
%           Switches to the statistics tab after the game ends
            app.TabGroup.SelectedTab = app.StatisticsTab;
%           Resets the timer label to 30s
            app.Label.Text = "32";
            
        end

        % Callback function
        function ClicktoContinueButtonPushed(app, event)
%           Switches to the statistics tab after the 
            app.TabGroup.SelectedTab = app.QRCodeTab;
%           Resets the defalut age and grade input values 
            app.gradeInput.Value = 2;
            app.ageInput.Value = 7;

        end

        % Button pushed function: PressToContinueButton
        function PressToContinueButtonPushed(app, event)
%           Method invoked when the button is pressed on the first tab 
%           which displays the information
            app.TabGroup.SelectedTab = app.TrialTab;
%           Runs the Morse Trial function which makes the user input a dot
%           and dash
            MorseKeyerTrial2(app);
        end

        % Button pushed function: ClicktoContinueButton
        function ClicktoContinueButtonPushed2(app, event)
%           Switches to the QR Code tab after the click to continue button
%           is pressed
            app.TabGroup.SelectedTab = app.QRCodeTab;
        end

        % Button pushed function: LastContinueButton
        function LastContinueButtonPushed(app, event)
%           Switches to the first tab again after the user is done with 
%           playing the game
            app.TabGroup.SelectedTab = app.InstructionsTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InstructionsTab = uitab(app.TabGroup);
            app.InstructionsTab.Title = 'Instructions Tab';
            app.PressToContinueButton = uibutton(app.InstructionsTab, 'push');
            app.PressToContinueButton.ButtonPushedFcn = createCallbackFcn(app, @PressToContinueButtonPushed, true);
            app.PressToContinueButton.Text = 'Press To Continue';
            app.ThisistheMorseCodeTypingtestLabel = uilabel(app.InstructionsTab);
            app.ThisistheMorseCodeTypingtestLabel.Text = {'Welcome to the Morse Code Typing test!'; ''; 'A dot is a short press of the button with a short pitched tone. '; 'A dash is a long press with a high pitched tone. '; 'Click on the button below using the mouse to continue.'};
            app.Image2 = uiimage(app.InstructionsTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Images ', 'Dot.jpg');
            app.DOTLabel = uilabel(app.InstructionsTab);
            app.DOTLabel.Text = 'DOT';
            app.Image3 = uiimage(app.InstructionsTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Images ', 'Dash.jpg');
            app.DASHLabel = uilabel(app.InstructionsTab);
            app.DASHLabel.Text = 'DASH';
            app.TrialTab = uitab(app.TabGroup);
            app.TrialTab.Title = 'Trial';
            app.LetsTryUsingBasicCharactersLabel = uilabel(app.TrialTab);
            app.LetsTryUsingBasicCharactersLabel.Text = 'Let''s Try Using Basic Characters';
            app.trialImage = uiimage(app.TrialTab);
            app.trialImage.ImageSource = fullfile(pathToMLAPP, 'Images ', 'Dot.jpg');
            app.morseInput = uilabel(app.TrialTab);
            app.morseInput.Text = '';
            app.YourInputLabel = uilabel(app.TrialTab);
            app.YourInputLabel.Text = 'Your Input:';
            app.trialInstruction = uilabel(app.TrialTab);
            app.trialInstruction.Text = 'Press the red button for a short while';
            app.resultButton = uibutton(app.TrialTab, 'push');
            app.resultButton.Text = 'Input a Dot';
            app.InformationTab = uitab(app.TabGroup);
            app.InformationTab.Title = 'Information';
            app.StartGame = uibutton(app.InformationTab, 'push');
            app.StartGame.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGame.Text = 'Start Game';
            app.EnteryourAgeDropDownLabel = uilabel(app.InformationTab);
            app.EnteryourAgeDropDownLabel.Text = 'Enter your Age';
            app.ageInput = uidropdown(app.InformationTab);
            app.ageInput.Items = {'7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61', '62', '63', '64', '65', '66', '67', '68', '69', '70', '71', '72', '73', '74', '75', '76', '77', '78', '79', '80'};
            app.ageInput.Value = '7';
            app.EnteryourGradeDropDownLabel = uilabel(app.InformationTab);
            app.EnteryourGradeDropDownLabel.Text = 'Enter your Grade';
            app.gradeInput = uidropdown(app.InformationTab);
            app.gradeInput.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '0'};
            app.gradeInput.Value = '2';
            app.NotePut0asyourgradeifyouarenotinschoolLabel = uilabel(app.InformationTab);
            app.NotePut0asyourgradeifyouarenotinschoolLabel.Text = 'Note: Put 0 as your grade if you are not in school';
            app.GameTab = uitab(app.TabGroup);
            app.GameTab.Title = 'Game';
            app.Label = uilabel(app.GameTab);
            app.Label.Visible = 'off';
            app.Label.Text = '32';
            app.MorseInputDynamic = uilabel(app.GameTab);
            app.MorseInputDynamic.Text = '';
            app.MorseInputLabel = uilabel(app.GameTab);
            app.MorseInputLabel.Text = 'Morse Input';
            app.LetterInputLabel = uilabel(app.GameTab);
            app.LetterInputLabel.Text = 'Letter Input';
            app.LetterInputDynamic = uilabel(app.GameTab);
            app.LetterInputDynamic.Text = '';
            app.Image = uiimage(app.GameTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images ', 'E.jpg');
            app.Debug1 = uilabel(app.GameTab);
            app.Debug2 = uilabel(app.GameTab);
            app.Debug2.Text = 'Label2';
            app.sLabel = uilabel(app.GameTab);
            app.sLabel.Text = 's';
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.ClicktoContinueButton = uibutton(app.StatisticsTab, 'push');
            app.ClicktoContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoContinueButtonPushed2, true);
            app.ClicktoContinueButton.Text = 'Click to Continue';
            app.accuracyOutput = uilabel(app.StatisticsTab);
            app.accuracyOutput.Text = '';
            app.averageSpeedOutput = uilabel(app.StatisticsTab);
            app.averageSpeedOutput.Text = '';
            app.YourAccuracyinageLabel = uilabel(app.StatisticsTab);
            app.YourAccuracyinageLabel.Text = 'Your Accuracy in %age:';
            app.YourAverageSpeedinCharactersPerMinuteLabel = uilabel(app.StatisticsTab);
            app.YourAverageSpeedinCharactersPerMinuteLabel.Text = 'Your Average Speed in Characters Per Minute:';
            app.CongratulationsonCompletingtheTypingTestLabel = uilabel(app.StatisticsTab);
            app.CongratulationsonCompletingtheTypingTestLabel.Text = 'Congratulations on Completing the Typing Test!!!';
            app.QRCodeTab = uitab(app.TabGroup);
            app.QRCodeTab.Title = 'QR Code ';
            app.ThankYouforUsingtheMorseCodeTypingTestLabel = uilabel(app.QRCodeTab);
            app.ThankYouforUsingtheMorseCodeTypingTestLabel.Text = 'Thank You for Using the Morse Code Typing Test!';
            app.Label3 = uilabel(app.QRCodeTab);
            app.Label3.Text = {'We would appreciate it if you could fill out a form so that we can improve this device and app.'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; 'You can scan the QR Code above using your phone to go to the form.'};
            app.Image4 = uiimage(app.QRCodeTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Images ', 'QRCode Final.png');
            app.LastContinueButton = uibutton(app.QRCodeTab, 'push');
            app.LastContinueButton.ButtonPushedFcn = createCallbackFcn(app, @LastContinueButtonPushed, true);
            app.LastContinueButton.Text = 'Click to Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = M6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end